classdef lidarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CenterVisualizationButton     matlab.ui.control.Button
        ErrorStatusTextArea           matlab.ui.control.TextArea
        ErrorStatusTextAreaLabel      matlab.ui.control.Label
        DistanceTextArea              matlab.ui.control.TextArea
        DistanceTextAreaLabel         matlab.ui.control.Label
        DisconnectButton              matlab.ui.control.Button
        Gauge                         matlab.ui.control.Gauge
        GaugeLabel                    matlab.ui.control.Label
        ReadDistancecmLabel           matlab.ui.control.Label
        CommandEditField              matlab.ui.control.EditField
        CommandEditFieldLabel         matlab.ui.control.Label
        SendCommandsLabel             matlab.ui.control.Label
        ConnecttoPICButton            matlab.ui.control.Button
        VisualizationButton           matlab.ui.control.Button
        PICConnectionStatusLamp       matlab.ui.control.Lamp
        PICConnectionStatusLampLabel  matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        SerialPortEditFieldLabel      matlab.ui.control.Label
        BaudRateEditField             matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        LiDARMeasurementsWithPIC18F87K22Label  matlab.ui.control.Label
        SendButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % Serial port device
    end
    
    methods (Access = private)
        %This function will update the gauge with the distance measurements
        %received from the sensor
        function readFunc(app)
            try
                while isvalid(app.device)
                    % Read data from the serial port
                    data = readline(app.device);
                    
                    % Parse to get the distance
                    charArr = convertStringsToChars(data);
                    dist = charArr(3:end-2);
                    dist = str2double(dist);    % Distance numeric

                    % Update gauge
                    app.Gauge.Value = dist;

                    % Update distance
                    app.DistanceTextArea.Value = data;
                    
                    % Pause for a short time to avoid high CPU usage
                    pause(0.1);
                end
            catch
                % Handle any exceptions (e.g., if the user closes the figure or errors occur)
                app.ErrorStatusTextArea.Value = 'Error: Invalid Device';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoPICButton
        function ConnecttoPICButtonPushed(app, event)
            baud = app.BaudRateEditField.Value;
            port = app.SerialPortEditField.Value;
            
            try
                % Attempt to connect
                app.device = serialport(port, baud);

                % Successful connection
                app.PICConnectionStatusLampLabel.Text = "Connection Successful!";
                app.PICConnectionStatusLamp.Color = 'g';    % Green lamp
                app.ErrorStatusTextArea.Value = 'No Errors';

            catch
                % Connection failed
                app.PICConnectionStatusLampLabel.Text = "Connection Failed!";
                app.PICConnectionStatusLamp.Color = 'r';    % Red lamp
            end


        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % Get the command from the text field
            command = app.CommandEditField.Value;
            command = convertCharsToStrings(command); % Convert to string

            % Send the command
            writeline(app.device, command);

            % Call read function if there is something to be read
            if(command == "DIST_ON" || command == "FAST_ON")
                app.readFunc();
            end

            % Otherwise pause
            pause on;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.device);
            app.PICConnectionStatusLampLabel.Text = "Connection Halted!";
            app.PICConnectionStatusLamp.Color = 'r';    % Green lamp
            pause on; % Wait for next selection
        end

        % Button pushed function: VisualizationButton
        function VisualizationButtonPushed(app, event)
            % Open Visualization window
            distOverTime(app.device);
        end

        % Button pushed function: CenterVisualizationButton
        function CenterVisualizationButtonPushed(app, event)
            % Call centered visualization
            centerVis(app.device);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send ';
            app.LiDARMeasurementsWithPIC18F87K22Label = uilabel(app.UIFigure);
            app.LiDARMeasurementsWithPIC18F87K22Label.Text = 'LiDAR Measurements With PIC18F87K22';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 19200;
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.PICConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.PICConnectionStatusLampLabel.Text = 'PIC Connection Status';
            app.PICConnectionStatusLamp = uilamp(app.UIFigure);
            app.VisualizationButton = uibutton(app.UIFigure, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.VisualizationButton.Text = 'Visualization';
            app.ConnecttoPICButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoPICButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPICButtonPushed, true);
            app.ConnecttoPICButton.Text = 'Connect to PIC';
            app.SendCommandsLabel = uilabel(app.UIFigure);
            app.SendCommandsLabel.Text = 'Send Commands';
            app.CommandEditFieldLabel = uilabel(app.UIFigure);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.UIFigure, 'text');
            app.ReadDistancecmLabel = uilabel(app.UIFigure);
            app.ReadDistancecmLabel.Text = 'Read Distance (cm)';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 500];
            app.Gauge.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.DistanceTextAreaLabel = uilabel(app.UIFigure);
            app.DistanceTextAreaLabel.Text = 'Distance';
            app.DistanceTextArea = uitextarea(app.UIFigure);
            app.ErrorStatusTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorStatusTextAreaLabel.Text = 'Error Status';
            app.ErrorStatusTextArea = uitextarea(app.UIFigure);
            app.CenterVisualizationButton = uibutton(app.UIFigure, 'push');
            app.CenterVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVisualizationButtonPushed, true);
            app.CenterVisualizationButton.Text = 'Center Visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end